classdef MyAstroVolley1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UIAxes    matlab.ui.control.UIAxes
    end

    
    % Copyright 2022 The MathWorks, Inc.

    properties (Access = public)
        xs      % x position of spaceships
        ys      % y position of spaceships
        Rs      % radius of circle encompassing ship
        xb      % x position of bodies
        yb      % y position of bodies
        Rb      % radius of bodies
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            fixedMission(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MyAstroVolley';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XLim = [-300 300];
            app.UIAxes.YLim = [-200 200];
            app.UIAxes.XTick = [-300 -250 -200 -150 -100 -50 0 50 100 150 200 250 300];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [-200 -150 -100 -50 0 50 100 150 200];
            app.UIAxes.YTickLabel = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MyAstroVolley1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end