classdef MyAstroVolley5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        VolleysLabel       matlab.ui.control.Label
        ofLabel2           matlab.ui.control.Label
        ofLabel1           matlab.ui.control.Label
        CallSignField2     matlab.ui.control.EditField
        CallSignField1     matlab.ui.control.EditField
        ClearScoresButton  matlab.ui.control.Button
        DeliveredField2    matlab.ui.control.NumericEditField
        AttemptedField2    matlab.ui.control.NumericEditField
        DeliveredField1    matlab.ui.control.NumericEditField
        AttemptedField1    matlab.ui.control.NumericEditField
        NewMissionButton   matlab.ui.control.Button
        LaunchButton2      matlab.ui.control.Button
        LaunchButton1      matlab.ui.control.Button
        VelocitySlider2    matlab.ui.control.Slider
        AngleSlider2       matlab.ui.control.Slider
        AngledegLabel_2    matlab.ui.control.Label
        VelocityLabel_2    matlab.ui.control.Label
        VelocitySlider1    matlab.ui.control.Slider
        VelocityLabel      matlab.ui.control.Label
        AngleSlider1       matlab.ui.control.Slider
        AngledegLabel      matlab.ui.control.Label
        UIAxes             matlab.ui.control.UIAxes
    end

    
    % Copyright 2022 The MathWorks, Inc.
    
    properties (Access = public)
        xs      % ship x-positions
        ys      % ship y-positions
        Rs      % ship size
        xb      % body x-positions
        yb      % body y-positions
        Rb      % body sizes
        hq      % quiver handle
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            fixedMission(app);
        end

        % Button pushed function: NewMissionButton
        function NewMissionButtonPushed(app, event)
            randomMission(app);
        end

        % Value changing function: AngleSlider1
        function AngleSlider1ValueChanging(app, event)
            ang = event.Value;
            vel = app.VelocitySlider1.Value;
            vx  = vel*cosd(ang);
            vy  = vel*sind(ang);
            delete(app.hq)
            app.hq = quiver(app.UIAxes,app.xs(1),app.ys(1),vx,vy,"Color","red");
        end

        % Value changing function: VelocitySlider1
        function VelocitySlider1ValueChanging(app, event)
            ang = app.AngleSlider1.Value;
            vel = event.Value;
            vx  = vel*cosd(ang);
            vy  = vel*sind(ang);
            delete(app.hq)
            app.hq = quiver(app.UIAxes,app.xs(1),app.ys(1),vx,vy,"Color","red");
        end

        % Value changing function: AngleSlider2
        function AngleSlider2ValueChanging(app, event)
            ang = event.Value;
            vel = app.VelocitySlider2.Value;
            vx  = -vel*cosd(ang);
            vy  =  vel*sind(ang);
            delete(app.hq)
            app.hq = quiver(app.UIAxes,app.xs(2),app.ys(2),vx,vy,"Color","red");
        end

        % Value changing function: VelocitySlider2
        function VelocitySlider2ValueChanging(app, event)
            ang = app.AngleSlider2.Value;
            vel = event.Value;
            vx  = -vel*cosd(ang);
            vy  =  vel*sind(ang);
            delete(app.hq)
            app.hq = quiver(app.UIAxes,app.xs(2),app.ys(2),vx,vy,"Color","red");
        end

        % Button pushed function: LaunchButton1
        function LaunchButton1Pushed(app, event)
            [traj,iEvent] = launchPayload(app,1); % ship = 1
            comet(app.UIAxes,traj(:,1),traj(:,2))

            app.AttemptedField1.Value = app.AttemptedField1.Value + 1;
            if (iEvent == 2) % event: reached ship 2
                app.DeliveredField1.Value = app.DeliveredField1.Value + 1;
            end
        end

        % Button pushed function: LaunchButton2
        function LaunchButton2Pushed(app, event)
            [traj,iEvent] = launchPayload(app,2); % ship = 2
            comet(app.UIAxes,traj(:,1),traj(:,2))

            app.AttemptedField2.Value = app.AttemptedField2.Value + 1;
            if (iEvent == 1) % event: reached ship 1
                app.DeliveredField2.Value = app.DeliveredField2.Value + 1;
            end
        end

        % Button pushed function: ClearScoresButton
        function ClearScoresButtonPushed(app, event)
            app.AttemptedField1.Value = 0;
            app.DeliveredField1.Value = 0;
            app.AttemptedField2.Value = 0;
            app.DeliveredField2.Value = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MyAstroVolley';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XLim = [-300 300];
            app.UIAxes.YLim = [-200 200];
            app.UIAxes.XTick = [-300 -250 -200 -150 -100 -50 0 50 100 150 200 250 300];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [-200 -150 -100 -50 0 50 100 150 200];
            app.UIAxes.YTickLabel = '';
            app.AngledegLabel = uilabel(app.UIFigure);
            app.AngledegLabel.Text = {'Angle'; '(deg)'};
            app.AngleSlider1 = uislider(app.UIFigure);
            app.AngleSlider1.Limits = [-90 90];
            app.AngleSlider1.MajorTicks = [-90 -80 -70 -60 -50 -40 -30 -20 -10 0 10 20 30 40 50 60 70 80 90];
            app.AngleSlider1.ValueChangingFcn = createCallbackFcn(app, @AngleSlider1ValueChanging, true);
            app.VelocityLabel = uilabel(app.UIFigure);
            app.VelocityLabel.Text = {'Velocity'; '(%)'};
            app.VelocitySlider1 = uislider(app.UIFigure);
            app.VelocitySlider1.MajorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.VelocitySlider1.ValueChangingFcn = createCallbackFcn(app, @VelocitySlider1ValueChanging, true);
            app.VelocitySlider1.Value = 50;
            app.VelocityLabel_2 = uilabel(app.UIFigure);
            app.VelocityLabel_2.Text = {'Velocity'; '(%)'};
            app.AngledegLabel_2 = uilabel(app.UIFigure);
            app.AngledegLabel_2.Text = {'Angle'; '(deg)'};
            app.AngleSlider2 = uislider(app.UIFigure);
            app.AngleSlider2.Limits = [-90 90];
            app.AngleSlider2.MajorTicks = [-90 -80 -70 -60 -50 -40 -30 -20 -10 0 10 20 30 40 50 60 70 80 90];
            app.AngleSlider2.ValueChangingFcn = createCallbackFcn(app, @AngleSlider2ValueChanging, true);
            app.VelocitySlider2 = uislider(app.UIFigure);
            app.VelocitySlider2.MajorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.VelocitySlider2.ValueChangingFcn = createCallbackFcn(app, @VelocitySlider2ValueChanging, true);
            app.VelocitySlider2.Value = 50;
            app.LaunchButton1 = uibutton(app.UIFigure, 'push');
            app.LaunchButton1.ButtonPushedFcn = createCallbackFcn(app, @LaunchButton1Pushed, true);
            app.LaunchButton1.Text = 'LAUNCH';
            app.LaunchButton2 = uibutton(app.UIFigure, 'push');
            app.LaunchButton2.ButtonPushedFcn = createCallbackFcn(app, @LaunchButton2Pushed, true);
            app.LaunchButton2.Text = 'LAUNCH';
            app.NewMissionButton = uibutton(app.UIFigure, 'push');
            app.NewMissionButton.ButtonPushedFcn = createCallbackFcn(app, @NewMissionButtonPushed, true);
            app.NewMissionButton.Text = {'New'; 'Mission'};
            app.AttemptedField1 = uieditfield(app.UIFigure, 'numeric');
            app.AttemptedField1.Limits = [0 Inf];
            app.AttemptedField1.Editable = 'off';
            app.DeliveredField1 = uieditfield(app.UIFigure, 'numeric');
            app.DeliveredField1.Limits = [0 Inf];
            app.DeliveredField1.Editable = 'off';
            app.AttemptedField2 = uieditfield(app.UIFigure, 'numeric');
            app.AttemptedField2.Limits = [0 Inf];
            app.AttemptedField2.Editable = 'off';
            app.DeliveredField2 = uieditfield(app.UIFigure, 'numeric');
            app.DeliveredField2.Limits = [0 Inf];
            app.DeliveredField2.Editable = 'off';
            app.ClearScoresButton = uibutton(app.UIFigure, 'push');
            app.ClearScoresButton.ButtonPushedFcn = createCallbackFcn(app, @ClearScoresButtonPushed, true);
            app.ClearScoresButton.Text = {'Clear'; 'Scores'};
            app.CallSignField1 = uieditfield(app.UIFigure, 'text');
            app.CallSignField1.Value = 'Call Sign: Alpha';
            app.CallSignField2 = uieditfield(app.UIFigure, 'text');
            app.CallSignField2.Value = 'Call Sign: Vector';
            app.ofLabel1 = uilabel(app.UIFigure);
            app.ofLabel1.Text = 'of';
            app.ofLabel2 = uilabel(app.UIFigure);
            app.ofLabel2.Text = 'of';
            app.VolleysLabel = uilabel(app.UIFigure);
            app.VolleysLabel.Text = {'Payload Volleys'; '(Delivered of Attempted)'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MyAstroVolley5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end