classdef OrbitalVelocity < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        LaunchButton1    matlab.ui.control.Button
        VelocitySlider1  matlab.ui.control.Slider
        VelocityLabel    matlab.ui.control.Label
        AngleSlider1     matlab.ui.control.Slider
        AngledegLabel    matlab.ui.control.Label
        UIAxes           matlab.ui.control.UIAxes
    end

    
    % Copyright 2022 The MathWorks, Inc.

    properties (Access = public)
        xs      % ship x-positions
        ys      % ship y-positions
        Rs      % ship size
        xb      % body x-positions
        yb      % body y-positions
        Rb      % body sizes
        hq      % quiver handle
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            g = groot; % center app
            app.UIFigure.Position(1:2) = (g.ScreenSize(3:4) - app.UIFigure.Position(3:4))/2;
            app.UIAxes.InnerPosition = app.UIAxes.Position; % remove padding for labels
            app.UIAxes.Toolbar.Visible = "off";
            disableDefaultInteractivity(app.UIAxes)
            
            ang = 0:1:360;     % angle, degrees
            xc = cosd(ang);    % circle x-points
            yc = sind(ang);    % circle y-points

            % Plot Initial Point (launchPayload use 2 ship postions to set vmax)
            app.xs = [ 0 0];   % ship x-position
            app.ys = [15 -45]; % ship y-position
            app.Rs = [.5 .5];  % ship size
            cs = [.7 .7 .7];   % ship color (normalized)

            fill(app.UIAxes,app.xs(1)+app.Rs(1)*xc,app.ys(1)+app.Rs(1)*yc,cs)
            hold(app.UIAxes,"on")
            grid(app.UIAxes,"on")

            % Plot Gravitational Body
            app.xb = 0;        % body x-position
            app.yb = 0;        % body y-position
            app.Rb = 10;       % body size
            cb = [0.466 0.674 0.188]; % body color

            fill(app.UIAxes,app.xb+app.Rb*xc,app.yb+app.Rb*yc,cb)
            axis(app.UIAxes,"equal")

            % Set display limits
            xlim(app.UIAxes,[-30 30])  % x-axis limits [min max]
            ylim(app.UIAxes,[-30 30])  % y-axis limits [min max]
            set(app.UIAxes,"XTick",-30:5:30,"YTick",-30:5:30)
        end

        % Value changing function: AngleSlider1
        function AngleSlider1ValueChanging(app, event)
            ang = event.Value;
            vel = app.VelocitySlider1.Value;
            vx  = vel*cosd(ang);
            vy  = vel*sind(ang);
            delete(app.hq)
            app.hq = quiver(app.UIAxes,app.xs(1),app.ys(1),vx,vy,0.1,"Color","red");
        end

        % Value changing function: VelocitySlider1
        function VelocitySlider1ValueChanging(app, event)
            ang = app.AngleSlider1.Value;
            vel = event.Value;
            vx  = vel*cosd(ang);
            vy  = vel*sind(ang);
            delete(app.hq)
            app.hq = quiver(app.UIAxes,app.xs(1),app.ys(1),vx,vy,0.1,"Color","red");
        end

        % Button pushed function: LaunchButton1
        function LaunchButton1Pushed(app, event)
            [traj,~] = launchPayload(app,1); % ship = 1
            comet(app.UIAxes,traj(:,1),traj(:,2))
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Orbital Velocity';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XLim = [-300 300];
            app.UIAxes.YLim = [-200 200];
            app.UIAxes.XTick = [-300 -250 -200 -150 -100 -50 0 50 100 150 200 250 300];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [-200 -150 -100 -50 0 50 100 150 200];
            app.UIAxes.YTickLabel = '';
            app.AngledegLabel = uilabel(app.UIFigure);
            app.AngledegLabel.Text = {'Angle'; '(deg)'};
            app.AngleSlider1 = uislider(app.UIFigure);
            app.AngleSlider1.Limits = [-180 180];
            app.AngleSlider1.MajorTicks = [-180 -160 -140 -120 -100 -80 -60 -40 -20 0 20 40 60 80 100 120 140 160 180];
            app.AngleSlider1.ValueChangingFcn = createCallbackFcn(app, @AngleSlider1ValueChanging, true);
            app.VelocityLabel = uilabel(app.UIFigure);
            app.VelocityLabel.Text = {'Velocity'; '(%)'};
            app.VelocitySlider1 = uislider(app.UIFigure);
            app.VelocitySlider1.MajorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.VelocitySlider1.ValueChangingFcn = createCallbackFcn(app, @VelocitySlider1ValueChanging, true);
            app.LaunchButton1 = uibutton(app.UIFigure, 'push');
            app.LaunchButton1.ButtonPushedFcn = createCallbackFcn(app, @LaunchButton1Pushed, true);
            app.LaunchButton1.Text = 'LAUNCH';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OrbitalVelocity

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end